classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceSqueak                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TabSegmentation                 matlab.ui.container.Tab
        PreProcessingoptionsPanel       matlab.ui.container.Panel
        CutoffFrequencykHZEditField     matlab.ui.control.NumericEditField
        CutoffFrequencykHZEditField_2Label  matlab.ui.control.Label
        FilteringCheckBox               matlab.ui.control.CheckBox
        SamplingFrequencyHzEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SegmentationOptionsPanel        matlab.ui.container.Panel
        Threshold2EditField             matlab.ui.control.NumericEditField
        Threshold2EditField_2Label      matlab.ui.control.Label
        Threshold1EditField             matlab.ui.control.NumericEditField
        Threshold1EditField_2Label      matlab.ui.control.Label
        FilesSelectionPanel_2           matlab.ui.container.Panel
        SegmentALLButton                matlab.ui.control.Button
        SegmentSelectedButton           matlab.ui.control.Button
        FilesDirectoryWAVLabel          matlab.ui.control.Label
        CurrentDirEditField             matlab.ui.control.EditField
        CurrentDirLabel                 matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        SelectDirectorywavfilesButton   matlab.ui.control.StateButton
        TabReviewSegmentation           matlab.ui.container.Tab
        RejectALLButton                 matlab.ui.control.Button
        AcceptALLButton                 matlab.ui.control.Button
        AudioFilename                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ENDButton                       matlab.ui.control.Button
        numVoc                          matlab.ui.control.Label
        SliderReview                    matlab.ui.control.Slider
        FileSelectionPanel              matlab.ui.container.Panel
        ChooseFileButton                matlab.ui.control.Button
        xlsxFilePathEditField           matlab.ui.control.EditField
        xlsxFilePathEditFieldLabel      matlab.ui.control.Label
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        RejectButton                    matlab.ui.control.Button
        AcceptButton                    matlab.ui.control.Button
        ClassificationTab               matlab.ui.container.Tab
        FilesSelectionPanel             matlab.ui.container.Panel
        ClassifyALLButton               matlab.ui.control.Button
        ClassifySelectedButton          matlab.ui.control.Button
        FilesDirectoryxlsxLabel         matlab.ui.control.Label
        CurrentDirectoryEditField       matlab.ui.control.EditField
        CurrentDirectoryEditFieldLabel  matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        SelectDirectoryButton_2         matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        path_segm = addpath(genpath('.\Functions'))
    end
    
    properties (Access = public)
        % Global variables
        separator = '/';

        % USVs vizualization
        table_review=[];
        current_USV = 0;
        Fs_review = 0;
        path_file_review = '';
        idx_review_reject = [];
        review_active = false;
        review_filename = '';

        % spectrogram paramenters
        window = 256
        n_overlap = 128
        fft_length = 1024
    end

    methods (Access = public)

        function display_spectrogram_review_tab(app)
            path_signal_review = app.table_review.Path{1};
            name_signal_review = app.table_review.Filename{1};

            init_USV = ceil(app.table_review.("Begining(s)")(app.current_USV) * app.Fs_review);
            end_USV = ceil(app.table_review.("End(s)")(app.current_USV) * app.Fs_review);
            [y_USV,Fs] =  audioread(strcat(path_signal_review,name_signal_review), [init_USV, end_USV]);

            [S,F,T,ps] = spectrogram(y_USV, app.window, app.n_overlap, app.fft_length, Fs, 'yaxis', 'MinThreshold', -110);
            %imagesc(app.spectrogramDisp, T, F, log(1+abs(S)) ); %plot the log spectrum

            spectro = flip(10*log10(abs(ps)));%convert spectrogram from power to db
            spectro(~isfinite(spectro)) = min(spectro(isfinite(spectro)));

            spectro_3d = uint8(255 * cat(3, mat2gray(spectro), mat2gray(spectro), mat2gray(spectro)));
            
            app.Image.ImageSource = spectro_3d;

            %set(app.spectrogramDisp,'YDir', 'normal');
            %app.spectrogramDisp.XLim=[0,max(T)];
            %app.spectrogramDisp.YLim=[0,max(F)];
            if ( ismember(app.current_USV, app.idx_review_reject) )
                app.numVoc.Text=['USV ',num2str(app.current_USV),' of ', num2str(size(app.table_review,1)), ' -- Rejected'];
            else
                app.numVoc.Text=['USV ',num2str(app.current_USV),' of ', num2str(size(app.table_review,1))];
            end

            app.SliderReview.Value = app.current_USV;
        end

        function reset_review_tab(app)
            app.current_USV = 0;
            app.table_review = [];
            app.xlsxFilePathEditField.Value = '';
            app.path_file_review = '';
            app.idx_review_reject = [];
            app.review_active = false;
            app.AudioFilename.Text = '';
            app.numVoc.Text = '';
            app.Image.ImageSource = uint8(255 * cat(3, zeros(5), zeros(5), zeros(5)));

            uialert(app.InterfaceSqueak,'Please select a .xlsx file to review','','Icon','warning'); 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectDirectorywavfilesButton
        function SelectDirectorywavfilesButtonValueChanged(app, event)
            value = app.SelectDirectorywavfilesButton.Value;

            if(value==1)
                selpath = uigetdir;
                app.CurrentDirEditField.Value = selpath;
                list_wav = dir(fullfile(selpath,'*.wav'));
                app.ListBox.Items={list_wav.name};
            end
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            if( ~ app.review_active )
                [file,path] = uigetfile('*.xlsx');
                app.review_filename = file;
                filename = [path, file];
                app.xlsxFilePathEditField.Value = filename;
                app.path_file_review.Value = filename;
                
                table = readtable(filename, 'VariableNamingRule', 'preserve');
                
                if ~isnan(table.("#")(1))
                    app.table_review = table;
                    app.Fs_review = table.SampleRate(1);
                    app.idx_review_reject = [];

                    % iniate graphics for review
                    app.review_active = true;
                    app.SliderReview.Limits = [1,size(app.table_review,1)];
                    app.AudioFilename.Text = app.review_filename;
                    app.current_USV = 1;
                    app.display_spectrogram_review_tab();
                    
                    uialert(app.InterfaceSqueak,'File selected!','','Icon','success');

                else
                    app.reset_review_tab();
                    uialert(app.InterfaceSqueak,'The selected file does not contains any segmented USVs.','','Icon','warning');
                end
            else
                uialert(app.InterfaceSqueak,'There is another file currently being review! Please press the button END to conclude previous file.','','Icon','warning');
            end
        end

        % Button pushed function: SegmentSelectedButton
        function SegmentSelectedButtonPushed(app, event)
            list_all = app.ListBox.Items;
            if(size(list_all,2)==0)
                %f = warndlg('No compatible files in the directory!','Warning');
                uialert(app.InterfaceSqueak,'No compatible files in the directory!','ERROR');
            else

                d = uiprogressdlg(app.InterfaceSqueak,'Title','Please Wait',...
                    'Message','File segmentation');


                threshold1 = app.Threshold1EditField.Value;
                threshold2 = app.Threshold2EditField.Value;
                cut_freq = app.CutoffFrequencykHZEditField.Value * 1000;
                Fs = app.SamplingFrequencyHzEditField.Value;
                filtering = app.FilteringCheckBox.Value;

                list_selected=app.ListBox.Value;
                if(isempty(list_selected))
                    uialert(app.InterfaceSqueak,'No selected files!','','Icon','warning');
                else
                    for i = 1:length(list_selected)

                        spectral_entropy_segmentation(strcat(app.CurrentDirEditField.Value,app.separator), ...
                            list_all{i},...
                            cut_freq,...
                            threshold1, threshold2, ...
                            filtering);

                        d.Value = i * (1 / length(list_selected));
                        d.Message = strcat(string(i),"/", string(length(list_selected)), " files completed");
                    end
                    close(d)
                    uialert(app.InterfaceSqueak,'Segmentation completed!','','Icon','success');
                end
            end
        end

        % Button pushed function: SegmentALLButton
        function SegmentALLButtonPushed(app, event)
            list_all=app.ListBox.Items;
            if(size(list_all,2)==0)
                %f = warndlg('No compatible files in the directory!','Warning');
                uialert(app.InterfaceSqueak,'No compatible files in the directory!','ERROR');
            else

                d = uiprogressdlg(app.InterfaceSqueak,'Title','Please Wait',...
                    'Message','File segmentation');

                threshold1 = app.Threshold1EditField.Value;
                threshold2 = app.Threshold2EditField.Value;
                cut_freq = app.CutoffFrequencykHZEditField.Value * 1000;
                Fs = app.SamplingFrequencyHzEditField.Value;
                filtering = app.FilteringCheckBox.Value;
                
                for i = 1:length(list_all)
                    spectral_entropy_segmentation(strcat(app.CurrentDirEditField.Value,app.separator), ...
                                                  list_all{i},...
                                                  cut_freq,...
                                                  threshold1, threshold2, ...
                                                  filtering);
                d.Value = i * (1 / length(list_all)); 
                    d.Message = strcat(string(i),"/", string(length(list_all)), " files completed");
                end
                close(d)
                uialert(app.InterfaceSqueak,'Segmentation completed!','','Icon','success');
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)

            if(~strcmp(app.path_file_review,'') & app.review_active  == true)
                
                if app.current_USV == size(app.table_review,1)
                    uialert(app.InterfaceSqueak,"No more segmented USVs!",'Warning','Icon','warning')
                else
                    app.current_USV = app.current_USV + 1;
                    app.display_spectrogram_review_tab();
                end
            end
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if(~strcmp(app.path_file_review,'') & app.review_active == true)
                
                if app.current_USV > 1
                    app.current_USV = app.current_USV - 1;
                    app.display_spectrogram_review_tab();
                end
                
                app.display_spectrogram_review_tab();
            end
        end

        % Value changing function: SliderReview
        function SliderReviewValueChanging(app, event)
            if(~strcmp(app.path_file_review,'') & app.review_active == true)
                changingValue = event.Value;
                app.current_USV = round(changingValue);
                
                app.display_spectrogram_review_tab();
            end
        end

        % Value changed function: SamplingFrequencyHzEditField
        function SamplingFrequencyHzEditFieldValueChanged(app, event)
            value = app.SamplingFrequencyHzEditField.Value;
            app.CutoffFrequencykHZSlider.Limits=[0,round((app.SamplingFrequencyHzEditField.Value/2)/1000)];
        end

        % Button pushed function: RejectButton
        function RejectButtonPushed(app, event)
            if(~strcmp(app.path_file_review,'') & app.review_active == true)
                
                app.idx_review_reject = [app.idx_review_reject,app.current_USV];
                app.idx_review_reject = unique(app.idx_review_reject);
                

                if app.current_USV < size(app.table_review,1)
                    app.current_USV = app.current_USV + 1;
                    app.display_spectrogram_review_tab();
                elseif app.current_USV == size(app.table_review,1)
                    app.display_spectrogram_review_tab();
                end
            end
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            if(~strcmp(app.path_file_review,'') & app.review_active == true)

                if(ismember(app.current_USV, app.idx_review_reject))
                    app.idx_review_reject = app.idx_review_reject(app.idx_review_reject~=app.current_USV);
                end
    
                if app.current_USV ~= size(app.table_review,1)
                    app.current_USV = app.current_USV + 1;
                end

                app.display_spectrogram_review_tab();
            end
        end

        % Button pushed function: ENDButton
        function ENDButtonPushed(app, event)
            if(~strcmp(app.path_file_review,'') & app.review_active == true)
                table = app.table_review;
                
                
                configs = table(1,{'SampleRate','Cut Freq','Thresh1','Thresh2','Path','Filename'});
                USVs = table(:,1:13);
                USVs(app.idx_review_reject,:) = [];
                USVs.("#") = [1:size(USVs,1)]';
                
                delete(app.path_file_review.Value)
                writetable(USVs, app.path_file_review.Value, 'Sheet', 1, 'Range', 'A1')
                writetable(configs, app.path_file_review.Value, 'Sheet', 1, 'Range', 'O1')

                app.reset_review_tab();

                uialert(app.InterfaceSqueak,'Review completed!','','Icon','success');
            end
        end

        % Value changed function: SelectDirectoryButton_2
        function SelectDirectoryButton_2ValueChanged(app, event)
            value = app.SelectDirectoryButton_2.Value;
            if(value==1)
                %[file,path] = uigetfile(fullfile('D:\Dados Tese','.wav'));
                selpath = uigetdir;
                app.CurrentDirectoryEditField.Value=selpath;
                list_xls = dir(fullfile(selpath,'*.xlsx'));
                app.ListBox_2.Items={list_xls.name};
            end
            
        end

        % Button pushed function: ClassifySelectedButton
        function ClassifySelectedButtonPushed(app, event)
            list_all=app.ListBox_2.Items;
            if(size(list_all,2)==0)
                uialert(app.InterfaceSqueak,'No compatible files in the directory!','ERROR');
            else
                d = uiprogressdlg(app.InterfaceSqueak,'Title','Please Wait',...
                    'Message','File Classification');

                list_selected=app.ListBox_2.Value;
                files_dir=app.CurrentDirectoryEditField.Value;
                
                if(isempty(list_selected))
                    uialert(app.InterfaceSqueak,'No selected files!','','Icon','warning');
                else
                    for i=1:length(list_selected)
                        filename=strcat(files_dir,app.separator,list_selected{i});
                        classify_xls(filename);

                        d.Value = i * (1 / length(list_selected));
                        d.Message = strcat(string(i),"/", string(length(list_selected)), " files completed");
                    end
                    close(d)
                    uialert(app.InterfaceSqueak,'Classification completed!','','Icon','success');
                end
            end
        end

        % Button pushed function: ClassifyALLButton
        function ClassifyALLButtonPushed(app, event)
            list_all=app.ListBox_2.Items;
            if(size(list_all,2)==0)
                uialert(app.InterfaceSqueak,'No compatible files in the directory!','ERROR');
            else
                d = uiprogressdlg(app.InterfaceSqueak,'Title','Please Wait',...
                    'Message','File Classification');

                files_dir = app.CurrentDirectoryEditField.Value;
                
                for i=1:length(list_all)
                    filename=strcat(files_dir,app.separator,list_all{i});
                    classify_xls(filename);
                 
                    d.Value = i * (1 / length(list_all));
                    d.Message = strcat(string(i),"/", string(length(list_all)), " files completed");
                end
                close(d)
                uialert(app.InterfaceSqueak,'Classification completed!','','Icon','success');
                
            end
        end

        % Value changed function: FilteringCheckBox
        function FilteringCheckBoxValueChanged(app, event)
            value = app.FilteringCheckBox.Value;
            
            if value == 1
                app.CutoffFrequencykHZEditField.Editable = "on";
            else
                app.CutoffFrequencykHZEditField.Editable = "off";
            end
        end

        % Button pushed function: AcceptALLButton
        function AcceptALLButtonPushed(app, event)
            app.idx_review_reject = [];
            app.display_spectrogram_review_tab();
        end

        % Button pushed function: RejectALLButton
        function RejectALLButtonPushed(app, event)
            app.idx_review_reject = 1:size(app.table_review,1);
            app.display_spectrogram_review_tab();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfaceSqueak = uifigure('Visible', 'off');
            app.InterfaceSqueak.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.InterfaceSqueak);
            app.TabSegmentation = uitab(app.TabGroup);
            app.TabSegmentation.Title = 'Segmentation';
            app.FilesSelectionPanel_2 = uipanel(app.TabSegmentation);
            app.FilesSelectionPanel_2.Title = 'Files Selection';
            app.SelectDirectorywavfilesButton = uibutton(app.FilesSelectionPanel_2, 'state');
            app.SelectDirectorywavfilesButton.ValueChangedFcn = createCallbackFcn(app, @SelectDirectorywavfilesButtonValueChanged, true);
            app.SelectDirectorywavfilesButton.Text = 'Select Directory (.wav files)';
            app.ListBox = uilistbox(app.FilesSelectionPanel_2);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.CurrentDirLabel = uilabel(app.FilesSelectionPanel_2);
            app.CurrentDirLabel.Text = 'Current Dir.';
            app.CurrentDirEditField = uieditfield(app.FilesSelectionPanel_2, 'text');
            app.FilesDirectoryWAVLabel = uilabel(app.FilesSelectionPanel_2);
            app.FilesDirectoryWAVLabel.Text = 'Files Directory (.WAV)';
            app.SegmentSelectedButton = uibutton(app.FilesSelectionPanel_2, 'push');
            app.SegmentSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentSelectedButtonPushed, true);
            app.SegmentSelectedButton.Text = 'Segment Selected';
            app.SegmentALLButton = uibutton(app.FilesSelectionPanel_2, 'push');
            app.SegmentALLButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentALLButtonPushed, true);
            app.SegmentALLButton.Text = 'Segment ALL';
            app.SegmentationOptionsPanel = uipanel(app.TabSegmentation);
            app.SegmentationOptionsPanel.Title = 'Segmentation Options';
            app.Threshold1EditField_2Label = uilabel(app.SegmentationOptionsPanel);
            app.Threshold1EditField_2Label.Text = 'Threshold 1';
            app.Threshold1EditField = uieditfield(app.SegmentationOptionsPanel, 'numeric');
            app.Threshold1EditField.Limits = [0 1];
            app.Threshold1EditField.Value = 0.97;
            app.Threshold2EditField_2Label = uilabel(app.SegmentationOptionsPanel);
            app.Threshold2EditField_2Label.Text = 'Threshold 2';
            app.Threshold2EditField = uieditfield(app.SegmentationOptionsPanel, 'numeric');
            app.Threshold2EditField.Limits = [0 1];
            app.Threshold2EditField.Value = 0.95;
            app.PreProcessingoptionsPanel = uipanel(app.TabSegmentation);
            app.PreProcessingoptionsPanel.Title = 'Pre-Processing options';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.PreProcessingoptionsPanel);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.PreProcessingoptionsPanel, 'numeric');
            app.SamplingFrequencyHzEditField.Limits = [0 Inf];
            app.SamplingFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyHzEditFieldValueChanged, true);
            app.SamplingFrequencyHzEditField.Value = 500000;
            app.FilteringCheckBox = uicheckbox(app.PreProcessingoptionsPanel);
            app.FilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilteringCheckBoxValueChanged, true);
            app.FilteringCheckBox.Text = 'Filtering';
            app.FilteringCheckBox.Value = true;
            app.CutoffFrequencykHZEditField_2Label = uilabel(app.PreProcessingoptionsPanel);
            app.CutoffFrequencykHZEditField_2Label.Text = {'Cut-off Frequency'; '(kHZ)'};
            app.CutoffFrequencykHZEditField = uieditfield(app.PreProcessingoptionsPanel, 'numeric');
            app.CutoffFrequencykHZEditField.Limits = [0 Inf];
            app.CutoffFrequencykHZEditField.Value = 30;
            app.TabReviewSegmentation = uitab(app.TabGroup);
            app.TabReviewSegmentation.Title = 'Review Segmentation';
            app.AcceptButton = uibutton(app.TabReviewSegmentation, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.RejectButton = uibutton(app.TabReviewSegmentation, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @RejectButtonPushed, true);
            app.RejectButton.Text = 'Reject';
            app.NextButton = uibutton(app.TabReviewSegmentation, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.TabReviewSegmentation, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.FileSelectionPanel = uipanel(app.TabReviewSegmentation);
            app.FileSelectionPanel.Title = 'File Selection';
            app.xlsxFilePathEditFieldLabel = uilabel(app.FileSelectionPanel);
            app.xlsxFilePathEditFieldLabel.Text = '.xlsx File Path';
            app.xlsxFilePathEditField = uieditfield(app.FileSelectionPanel, 'text');
            app.ChooseFileButton = uibutton(app.FileSelectionPanel, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.SliderReview = uislider(app.TabReviewSegmentation);
            app.SliderReview.ValueChangingFcn = createCallbackFcn(app, @SliderReviewValueChanging, true);
            app.numVoc = uilabel(app.TabReviewSegmentation);
            app.numVoc.Text = '';
            app.ENDButton = uibutton(app.TabReviewSegmentation, 'push');
            app.ENDButton.ButtonPushedFcn = createCallbackFcn(app, @ENDButtonPushed, true);
            app.ENDButton.Text = 'END';
            app.Image = uiimage(app.TabReviewSegmentation);
            app.AudioFilename = uilabel(app.TabReviewSegmentation);
            app.AudioFilename.Text = '';
            app.AcceptALLButton = uibutton(app.TabReviewSegmentation, 'push');
            app.AcceptALLButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptALLButtonPushed, true);
            app.AcceptALLButton.Text = 'Accept ALL';
            app.RejectALLButton = uibutton(app.TabReviewSegmentation, 'push');
            app.RejectALLButton.ButtonPushedFcn = createCallbackFcn(app, @RejectALLButtonPushed, true);
            app.RejectALLButton.Text = 'Reject ALL';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.FilesSelectionPanel = uipanel(app.ClassificationTab);
            app.FilesSelectionPanel.Title = 'Files Selection';
            app.SelectDirectoryButton_2 = uibutton(app.FilesSelectionPanel, 'state');
            app.SelectDirectoryButton_2.ValueChangedFcn = createCallbackFcn(app, @SelectDirectoryButton_2ValueChanged, true);
            app.SelectDirectoryButton_2.Text = 'Select Directory';
            app.ListBox_2 = uilistbox(app.FilesSelectionPanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {};
            app.CurrentDirectoryEditFieldLabel = uilabel(app.FilesSelectionPanel);
            app.CurrentDirectoryEditFieldLabel.Text = 'Current Directory';
            app.CurrentDirectoryEditField = uieditfield(app.FilesSelectionPanel, 'text');
            app.FilesDirectoryxlsxLabel = uilabel(app.FilesSelectionPanel);
            app.FilesDirectoryxlsxLabel.Text = 'Files Directory (.xlsx)';
            app.ClassifySelectedButton = uibutton(app.FilesSelectionPanel, 'push');
            app.ClassifySelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifySelectedButtonPushed, true);
            app.ClassifySelectedButton.Text = 'Classify Selected';
            app.ClassifyALLButton = uibutton(app.FilesSelectionPanel, 'push');
            app.ClassifyALLButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyALLButtonPushed, true);
            app.ClassifyALLButton.Text = 'Classify ALL';
            % Show the figure after all components are created
            app.InterfaceSqueak.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceSqueak)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceSqueak)
        end
    end
end